classdef hw1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SaveButton        matlab.ui.control.Button
        FunctionLabel     matlab.ui.control.Label
        Slider            matlab.ui.control.Slider
        FunctionDropDown  matlab.ui.control.DropDown
        Image_3           matlab.ui.control.Image
        Image_2           matlab.ui.control.Image
        Image             matlab.ui.control.Image
        LoadButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        %Property % Description
        im_L
        im_R
        im_d

    end
    
    methods (Access = private)
        
        function show(app)
        % Read the Value property of the DropDown component
        selected_option = app.FunctionDropDown.Value;
        
        % Set the visibility of dropdown, images, and slider to 'On'
        app.FunctionDropDown.Visible = 'on';
        app.FunctionLabel.Visible='on';
        app.Image.Visible = 'on';
        app.Image_2.Visible = 'on';
        app.Image_3.Visible = 'on';
        app.Slider.Visible = 'on';
        
        % Use a switch case based on the selected option
        switch selected_option
            case 1
                % Display grayscale depthmap
                % Convert grayscale depthmap to RGB using cat function
                rgb_image = cat(3, app.im_d, app.im_d, app.im_d);
                app.Image_3.ImageSource = rgb_image;
                
                % Hide the slider component
                app.Slider.Visible = 'off';
                app.SaveButton.Visible='on';
                
            case 2
                % Show Slider component
                app.Slider.Visible = 'on';
            
                % Adjust image depth based on slider value
                w = abs(app.Slider.Value/100 - app.im_d);
            
                % Apply filters for refocusing
                im_blur = imfilter(app.im_L, ones(11)/121);
                im_sharp = imfilter(app.im_L, [-0.05 -0.05 -0.05; -0.05 1.4 -0.05; -0.05 -0.05 -0.05]);
            
                % Normalize the images to [0, 1] range
                im_blur = mat2gray(im_blur);
                im_sharp = mat2gray(im_sharp);
            
                % Ensure w matches the number of channels in im_sharp and im_blur
                w = repmat(w, [1, 1, size(im_sharp, 3)]);
            
                % Create refocused image
                im_refocus = w .* im_sharp + (1 - w) .* im_blur;
            
                % Update the Image component
                app.Image_3.ImageSource = im_refocus;
                app.SaveButton.Visible='on';

            case 3
                % Show Slider component
                app.Slider.Visible = 'on';
            
                % Compute saturation adjustment based on Slider value
                saturation_value = app.Slider.Value/100; % Slider value is in the range [0, 1]
                
                % Convert left image to HSV color space
                hsv_image = rgb2hsv(app.im_L);
                
                % Adjust saturation based on depth map and Slider value
                % Depth map is assumed to be in the range [0, 1]
                % Apply saturation adjustment inversely proportional to depth for Slider value 0
                % Apply saturation adjustment proportional to depth for Slider value 1
                adjusted_saturation = app.im_d * (1 - saturation_value) + (1 - app.im_d) * saturation_value;
                
                % Apply saturation adjustment
                adjusted_hsv_image = hsv_image;
                adjusted_hsv_image(:, :, 2) = adjusted_saturation;
                
                % Convert back to RGB color space
                adjusted_rgb_image = hsv2rgb(adjusted_hsv_image);
                
                % Update the Image component
                app.Image_3.ImageSource = adjusted_rgb_image;
                app.SaveButton.Visible='on';
 
        end
    end
        
        function save(app)
            % Prompt user to select a file location for saving the image
            [filename, pathname] = uiputfile({'*.png';'*.jpg';'*.bmp'}, 'Save Image');
            if filename == 0 % User canceled
                return;
            end
        
            % Construct the full path
            fullpath = fullfile(pathname, filename);
        
            % Get the current image from the Image_3 component
            adjusted_image = app.Image_3.ImageSource;
        
            % Check if the adjusted image is empty
            if isempty(adjusted_image)
                disp('No image to save.');
                return;
            end
        
            % Save the image
            imwrite(adjusted_image, fullpath);
        
            % Display a message indicating successful save
            disp(['Image saved as: ', fullpath]);
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Image_3.Visible='off';
            app.Slider.Visible='off';
            app.FunctionDropDown.Visible='off';
            app.FunctionLabel.Visible='off';
            app.SaveButton.Visible='off';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            clc
            
            [filename1, pathname1] =uigetfile({'*.png';'*.*'}, 'Pick two files'); %讀取[路徑 檔名]
            [filename2, pathname2] =uigetfile({'*.png';'*.*'}, 'Pick two files'); %讀取[路徑 檔名]
            
            app.im_L = imread([pathname1 filename1]); %讀取影像矩陣
            app.im_R = imread([pathname2 filename2]); %讀取影像矩陣
            %imshow(im, 'parent',app.UIAxes) %雖然可用 imshow，但需指定'parent'的axes物件
            %app.Image.ImageSource = [pathname filename];
            
            app.Image.ImageSource = app.im_L; %ImageSource 可用影像矩陣，或[路徑 檔名]
            app.Image_2.ImageSource = app.im_R;

            [depthmap, ~, ~, ~] = stereomatch(app.im_L, app.im_R, 31, 50, 0);
            % Find the minimum and maximum values in the depthmap
            min_depth = min(depthmap(:));
            max_depth = max(depthmap(:));

            % Normalize the depthmap to the range [0, 1]
            normalized_depthmap = (depthmap - min_depth) / (max_depth - min_depth);

            % Store the normalized depth map in app.im_d
            app.im_d = normalized_depthmap;

            show(app);       
            
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            show(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            show(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            save(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'Depthmap', 'Refocus', 'Colorization'};
            app.FunctionDropDown.ItemsData = [1 2 3];
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 1;
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FunctionLabel = uilabel(app.UIFigure);
            app.FunctionLabel.Text = 'Function';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hw1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end